classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout4               matlab.ui.container.GridLayout
        GridLayout5_2             matlab.ui.container.GridLayout
        RsultatDWT2Label          matlab.ui.control.Label
        GridLayout6               matlab.ui.container.GridLayout
        GridLayout7               matlab.ui.container.GridLayout
        GridLayout9               matlab.ui.container.GridLayout
        SWT2Label                 matlab.ui.control.Label
        GridLayout8               matlab.ui.container.GridLayout
        DWT2Label                 matlab.ui.control.Label
        valuationdesperformancesduCBIRLabel  matlab.ui.control.Label
        GridLayout5               matlab.ui.container.GridLayout
        RsultatSWT2Label          matlab.ui.control.Label
        GridLayout2               matlab.ui.container.GridLayout
        RechercheButton_2         matlab.ui.control.StateButton
        GridLayout3               matlab.ui.container.GridLayout
        NbrImageEditField         matlab.ui.control.NumericEditField
        NbrImageEditFieldLabel    matlab.ui.control.Label
        NbrsclasseEditField       matlab.ui.control.EditField
        NbrsclasseEditFieldLabel  matlab.ui.control.Label
        NbrniveauEditField        matlab.ui.control.EditField
        NbrniveauEditFieldLabel   matlab.ui.control.Label
        ParametreLabel            matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        OuvririmagerfrenceButton  matlab.ui.control.Button
        ProjetCIBR2023Label       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OuvririmagerfrenceButton
        function OuvririmagerfrenceButtonPushed(app, event)
             % Boîte de dialogue pour choisir le fichier image
         [fileName, filePath] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif', 'Images (*.png, *.jpg, *.jpeg, *.tif)'; '*.*', 'Tous les fichiers'}, 'Sélectionnez une image');
          % Vérifier si l'utilisateur a annulé la sélection
            if isequal(fileName, 0) || isequal(filePath, 0)
                return;
            end
        
            % Chemin complet de l'image
            imagePath = fullfile(filePath, fileName);
 
            app.Image.ImageSource=imread(imagePath);
        end

        % Callback function
        function ImageClicked(app, event)
            
        end

        % Callback function
        function ImageClicked2(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ProjetCIBR2023Label = uilabel(app.GridLayout2);
            app.ProjetCIBR2023Label.Text = 'Projet CIBR 2023';
            app.OuvririmagerfrenceButton = uibutton(app.GridLayout2, 'push');
            app.OuvririmagerfrenceButton.ButtonPushedFcn = createCallbackFcn(app, @OuvririmagerfrenceButtonPushed, true);
            app.OuvririmagerfrenceButton.Tag = 'btnChargerImage';
            app.OuvririmagerfrenceButton.Icon = fullfile(pathToMLAPP, '2849798_upload_multimedia_icon.png');
            app.OuvririmagerfrenceButton.Text = 'Ouvrir image référence';
            app.Image = uiimage(app.GridLayout2);
            app.Image.Tag = 'affichImag';
            app.ParametreLabel = uilabel(app.GridLayout2);
            app.ParametreLabel.Text = 'Parametre :';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.NbrniveauEditFieldLabel = uilabel(app.GridLayout3);
            app.NbrniveauEditFieldLabel.Text = 'Nbr niveau:';
            app.NbrniveauEditField = uieditfield(app.GridLayout3, 'text');
            app.NbrniveauEditField.Value = '1';
            app.NbrsclasseEditFieldLabel = uilabel(app.GridLayout3);
            app.NbrsclasseEditFieldLabel.Text = 'Nbr s-classe';
            app.NbrsclasseEditField = uieditfield(app.GridLayout3, 'text');
            app.NbrsclasseEditField.Value = '16';
            app.NbrImageEditFieldLabel = uilabel(app.GridLayout3);
            app.NbrImageEditFieldLabel.Text = 'NbrImage';
            app.NbrImageEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NbrImageEditField.Value = 16;
            app.RechercheButton_2 = uibutton(app.GridLayout2, 'state');
            app.RechercheButton_2.Text = 'Recherche';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.RsultatSWT2Label = uilabel(app.GridLayout5);
            app.RsultatSWT2Label.Text = 'Résultat SWT2:';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.valuationdesperformancesduCBIRLabel = uilabel(app.GridLayout6);
            app.valuationdesperformancesduCBIRLabel.Text = 'Évaluation des performances du CBIR:';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.DWT2Label = uilabel(app.GridLayout8);
            app.DWT2Label.Text = 'DWT2:';
            app.GridLayout9 = uigridlayout(app.GridLayout7);
            app.SWT2Label = uilabel(app.GridLayout9);
            app.SWT2Label.Text = 'SWT2:';
            app.GridLayout5_2 = uigridlayout(app.GridLayout4);
            app.RsultatDWT2Label = uilabel(app.GridLayout5_2);
            app.RsultatDWT2Label.Text = 'Résultat DWT2:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end